classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LoadDatasetsButton  matlab.ui.control.Button
        ImageAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function update_image(app,target_folder)
            imageFiles=dir([target_folder '/*.jpg']);
            
            
            imageFileName = imageFiles(1).name;
            fullFileName = fullfile(target_folder, imageFiles(1).name );
            
            try
                im = imread(fullFileName);
                
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            imagesc(app.ImageAxes,im);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
        end

        % Callback function: ImageAxes, LoadDatasetsButton
        function LoadDatasetsButtonPushed(app, event)
            
            % Display uigetfile dialog
            
            target_folder = uigetdir('Load a dataset ');
            
            % Make sure user didn't cancel uigetfile dialog
            if ~isfolder(target_folder)
                errorMessage = sprintf('Error: The target folder does not exist:\n%s', target_folder);
                uiwait(warndlg(errorMessage));
                return;
            end
            
            update_image(app,target_folder);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadDatasetsButton = uibutton(app.UIFigure, 'push');
            app.LoadDatasetsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.LoadDatasetsButton.Text = 'Load Datasets';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Output')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.ButtonDownFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end